classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel3                      matlab.ui.container.Panel
        SaveButton                  matlab.ui.control.Button
        Panel_3                     matlab.ui.container.Panel
        file_name                   matlab.ui.control.EditField
        FilenameEditFieldLabel      matlab.ui.control.Label
        VolumeSlider_3              matlab.ui.control.Slider
        VolumeSlider_3Label         matlab.ui.control.Label
        ResumeButton_3              matlab.ui.control.Button
        StopButton_3                matlab.ui.control.Button
        PauseButton_3               matlab.ui.control.Button
        PlayButton_3                matlab.ui.control.Button
        TransitionInput             matlab.ui.control.NumericEditField
        TransitionatEditFieldLabel  matlab.ui.control.Label
        MakeTransitionButton        matlab.ui.control.Button
        UIAxes_3                    matlab.ui.control.UIAxes
        DJSoundMixerLabel           matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        VolumeSlider                matlab.ui.control.Slider
        VolumeSlider_4Label_2       matlab.ui.control.Label
        ResumeButton                matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        ResumeButton_2              matlab.ui.control.Button
        VolumeSlider_2              matlab.ui.control.Slider
        VolumeSlider_4Label         matlab.ui.control.Label
        StopButton_2                matlab.ui.control.Button
        PauseButton_2               matlab.ui.control.Button
        PlayButton_2                matlab.ui.control.Button
        DurationPanel_2             matlab.ui.container.Panel
        secondsLabel_2              matlab.ui.control.Label
        DurationLabel_2             matlab.ui.control.Label
        Song2Label                  matlab.ui.control.Label
        RecordButton_2              matlab.ui.control.Button
        ChooseSongButton_2          matlab.ui.control.Button
        songName_2                  matlab.ui.control.EditField
        DurationPanel               matlab.ui.container.Panel
        secondsLabel                matlab.ui.control.Label
        DurationLabel               matlab.ui.control.Label
        Song1Label                  matlab.ui.control.Label
        RecordButton                matlab.ui.control.Button
        ChooseSongButton            matlab.ui.control.Button
        songName                    matlab.ui.control.EditField
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        song1 % Audio file one
        song2 % Audio file two
        songPlayer1 % Audio player for song one
        songPlayer2 % Audio player for song two
        FS1 % Sampling frequency for song one
        FS2 % Sampling frequency for song two
        FS % Sampling frequency for combined song
        transitionTime % Time at which to transition from first to second song
        finalAudio % Combined / transitioned audio files
        y3 % Final audio data
        record1 = false % Check if first recording button is pressed
        record2 = false % Check if second recording button is pressed
        rec1 = audiorecorder % Create first audio recorder object
        rec2 = audiorecorder % Create second audio recorder object
        original_y3
    end
    
    methods (Access = private)
        
        
        function song1 = addSong1(app)  % Function to add the first song to the app
            [y, Fs] = audioread(app.song1);
            app.songPlayer1=audioplayer(y, Fs) ;
            dt = 1/Fs;
            t = 0:dt:(length(y)*dt)-dt; 
            plot(app.UIAxes,t, y); % Reference: https://www.mathworks.com/matlabcentral/answers/22112-how-to-plot-wav-file
          
            duration = length(y)./Fs; % Duration label
            app.secondsLabel.Text = string(duration) + ' seconds | ' + string(duration/60) + ' minutes';
        end
        
        function song2 = addSong2(app) % Function to add the second song to the app
            [y, Fs] = audioread(app.song2);
            app.songPlayer2=audioplayer(y, Fs);
            dt = 1/Fs;
            t = 0:dt:(length(y)*dt)-dt;
            plot(app.UIAxes_2,t, y); % Reference: https://www.mathworks.com/matlabcentral/answers/22112-how-to-plot-wav-file

            duration = length(y)./Fs; % Duration label
            app.secondsLabel_2.Text = string(duration) + ' seconds | ' + string(duration/60) + ' minutes';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseSongButton
        function ChooseSongButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.mp3; *.wav; *.ogg; *.aiff'},'Song 1'); % Reference: https://www.mathworks.com/help/matlab/ref/uigetfile.html
            figure(app.UIFigure) %Reference: https://uk.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            if(isa(filename,'double')==1)
                return;
            end
            app.song1 = strcat(filepath, filename);
            app.songName.Value=filename;
            addSong1(app);
        end

        % Button pushed function: ChooseSongButton_2
        function ChooseSongButton2Pushed(app, event)
            [filename, filepath] = uigetfile({'*.mp3; *.wav; *.ogg; *.aiff'},'Song 2'); % Reference: https://www.mathworks.com/help/matlab/ref/uigetfile.html
            figure(app.UIFigure) %Reference: https://uk.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            if(isa(filename,'double')==1)
                return;
            end
            app.song2 = strcat(filepath, filename);
            app.songName_2.Value=filename;
            addSong2(app);
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
             if(app.song2==0)
                return;
             end    
            [y, Fs] = audioread(app.song2);
            y = y/2000;
            dt=1/Fs;
            y=y*app.VolumeSlider_2.Value;
            app.songPlayer2=audioplayer(y, Fs);
            t = 0:dt:(length(y)*dt)-dt; 
            plot(app.UIAxes_2,t, y); % Reference: https://www.mathworks.com/matlabcentral/answers/22112-how-to-plot-wav-file
            
            play(app.songPlayer2);
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
            if(app.finalAudio==0)
                return;
            end
            app.y3 = app.original_y3;
            app.y3 = app.y3/2000;
            app.y3=app.y3*app.VolumeSlider_3.Value;
            app.finalAudio = audioplayer(app.y3, app.FS);

            dt=1/app.FS;
            t = 0:dt:(length(app.y3)*dt)-dt; 
            plot(app.UIAxes_3,t, app.y3); % Reference: https://www.mathworks.com/matlabcentral/answers/22112-how-to-plot-wav-file
            
            play(app.finalAudio);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            stop(app.songPlayer2);
        end

        % Button pushed function: StopButton_3
        function StopButton_3Pushed(app, event)
            stop(app.finalAudio);
        end

        % Button pushed function: PauseButton_2
        function PauseButton_2Pushed(app, event)
            pause(app.songPlayer2);
        end

        % Button pushed function: ResumeButton_2
        function ResumeButton_2Pushed(app, event)
            resume(app.songPlayer2);
        end

        % Button pushed function: PauseButton_3
        function PauseButton_3Pushed(app, event)
            pause(app.finalAudio);
        end

        % Button pushed function: ResumeButton_3
        function ResumeButton_3Pushed(app, event)
            resume(app.finalAudio);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
             if(app.song1==0)
                return;
            end
            
            [y, Fs] = audioread(app.song1);
            y = y/2000;
            [m,n]=size(y);
            dt=1/Fs;
            t=dt*(0:m-1);
            y=y*app.VolumeSlider.Value;
            app.songPlayer1=audioplayer(y, Fs);
            t = 0:dt:(length(y)*dt)-dt; 
            plot(app.UIAxes,t, y); % Reference: https://www.mathworks.com/matlabcentral/answers/22112-how-to-plot-wav-file
            
            play(app.songPlayer1);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.songPlayer1);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(app.songPlayer1);
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            resume(app.songPlayer1);
        end

        % Button pushed function: MakeTransitionButton
        function MakeTransitionButtonPushed(app, event)
            [y1, Fs1] = audioread(app.song1);
            [y2, Fs2] = audioread(app.song2); 
            app.FS = (Fs1+Fs2)/2;
            app.transitionTime = app.TransitionInput.Value;

            % Gradually lower the volume of the first song
            y1Lower1 = y1/2;
            y1Lower2 = y1/4;
            y1Lower3 = y1/6;
            y1Lower4 = y1/8;
            y1Lower5 = y1/10;
            firstSong = y1(1:app.FS*app.transitionTime,:);
            firstSongLower1 = y1Lower1(app.FS*app.transitionTime:app.FS*(app.transitionTime+2), :);
            firstSongLower2 = y1Lower2(app.FS*(app.transitionTime+2):app.FS*(app.transitionTime+4), :);
            firstSongLower3 = y1Lower3(app.FS*(app.transitionTime+4):app.FS*(app.transitionTime+6), :);
            firstSongLower4 = y1Lower4(app.FS*(app.transitionTime+6):app.FS*(app.transitionTime+8), :);
            firstSongLower5 = y1Lower5(app.FS*(app.transitionTime+8):app.FS*(app.transitionTime+10), :);

            % Gradually increase the volume of the second song
            y2Lower1 = y2/10;
            y2Lower2 = y2/8;
            y2Lower3 = y2/6;
            y2Lower4 = y2/4;
            y2Lower5 = y2/2;
            secondSong = y2(app.FS*(app.transitionTime+10):end, :);
            secondSongLower1 = y2Lower1(app.FS*app.transitionTime:app.FS*(app.transitionTime+2), :);
            secondSongLower2 = y2Lower2(app.FS*(app.transitionTime+2):app.FS*(app.transitionTime+4), :);
            secondSongLower3 = y2Lower3(app.FS*(app.transitionTime+4):app.FS*(app.transitionTime+6), :);
            secondSongLower4 = y2Lower4(app.FS*(app.transitionTime+6):app.FS*(app.transitionTime+8), :);
            secondSongLower5 = y2Lower5(app.FS*(app.transitionTime+8):app.FS*(app.transitionTime+10), :);

            % Combine both songs with the transition
            app.y3 = [firstSong; firstSongLower1; firstSongLower2; firstSongLower3; firstSongLower4; firstSongLower5; secondSongLower1; secondSongLower2; secondSongLower3; secondSongLower4; secondSongLower5; secondSong];
            app.original_y3 = app.y3;

            dt=1/app.FS;
            t = 0:dt:(length(app.y3)*dt)-dt; 
            plot(app.UIAxes_3,t, app.y3); % Reference: https://www.mathworks.com/matlabcentral/answers/22112-how-to-plot-wav-file
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            newSong = app.file_name.Value + ".wav";
            audiowrite(newSong, app.original_y3, app.FS);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            Fs = 8000;
            if (app.record1 == false)
                record(app.rec1)
                app.record1 = true;
                app.RecordButton.Text = 'Stop';
                app.songName.Value = 'Recording...';
            else
                stop(app.rec1)
                app.RecordButton.Text = 'Record';
                app.record1 = false;
                app.songName.Value = 'Done';

                x = getaudiodata(app.rec1);
                audiowrite('RecordingOne.wav', x, Fs);  
            end
        end

        % Button pushed function: RecordButton_2
        function RecordButton_2Pushed(app, event)
             Fs = 8000;
            if (app.record2 == false)
                record(app.rec2)
                app.record2 = true;
                app.RecordButton_2.Text = 'Stop';
                app.songName_2.Value = 'Recording...';
            else
                stop(app.rec2)
                app.RecordButton.Text = 'Record';
                app.record2 = false;
                app.songName_2.Value = 'Done';

                x = getaudiodata(app.rec2);
                audiowrite('RecordingTwo.wav', x, Fs);  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Panel);
            zlabel(app.UIAxes_2, 'Z')
            app.songName = uieditfield(app.Panel, 'text');
            app.ChooseSongButton = uibutton(app.Panel, 'push');
            app.ChooseSongButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseSongButtonPushed, true);
            app.ChooseSongButton.Text = 'Choose song';
            app.RecordButton = uibutton(app.Panel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.Song1Label = uilabel(app.Panel);
            app.Song1Label.Text = 'Song 1';
            app.DurationPanel = uipanel(app.Panel);
            app.DurationLabel = uilabel(app.DurationPanel);
            app.DurationLabel.Text = 'Duration:';
            app.secondsLabel = uilabel(app.DurationPanel);
            app.secondsLabel.Text = '0';
            app.songName_2 = uieditfield(app.Panel, 'text');
            app.ChooseSongButton_2 = uibutton(app.Panel, 'push');
            app.ChooseSongButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseSongButton2Pushed, true);
            app.ChooseSongButton_2.Text = 'Choose song';
            app.RecordButton_2 = uibutton(app.Panel, 'push');
            app.RecordButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_2Pushed, true);
            app.RecordButton_2.Text = 'Record';
            app.Song2Label = uilabel(app.Panel);
            app.Song2Label.Text = 'Song 2';
            app.DurationPanel_2 = uipanel(app.Panel);
            app.DurationLabel_2 = uilabel(app.DurationPanel_2);
            app.DurationLabel_2.Text = 'Duration:';
            app.secondsLabel_2 = uilabel(app.DurationPanel_2);
            app.secondsLabel_2.Text = '0';
            app.PlayButton_2 = uibutton(app.Panel, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.PauseButton_2 = uibutton(app.Panel, 'push');
            app.PauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_2Pushed, true);
            app.PauseButton_2.Text = 'Pause';
            app.StopButton_2 = uibutton(app.Panel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.VolumeSlider_4Label = uilabel(app.Panel);
            app.VolumeSlider_4Label.Text = 'Volume';
            app.VolumeSlider_2 = uislider(app.Panel);
            app.VolumeSlider_2.Limits = [1 1000];
            app.VolumeSlider_2.MajorTicks = [];
            app.VolumeSlider_2.MinorTicks = [];
            app.VolumeSlider_2.Value = 1;
            app.ResumeButton_2 = uibutton(app.Panel, 'push');
            app.ResumeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResumeButton_2Pushed, true);
            app.ResumeButton_2.Text = 'Resume';
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResumeButton = uibutton(app.Panel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.VolumeSlider_4Label_2 = uilabel(app.Panel);
            app.VolumeSlider_4Label_2.Text = 'Volume';
            app.VolumeSlider = uislider(app.Panel);
            app.VolumeSlider.Limits = [1 1000];
            app.VolumeSlider.MajorTicks = [];
            app.VolumeSlider.MinorTicks = [];
            app.VolumeSlider.Value = 1;
            app.DJSoundMixerLabel = uilabel(app.UIFigure);
            app.DJSoundMixerLabel.Text = 'DJ Sound Mixer';
            app.Panel3 = uipanel(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.Panel3);
            app.MakeTransitionButton = uibutton(app.Panel3, 'push');
            app.MakeTransitionButton.ButtonPushedFcn = createCallbackFcn(app, @MakeTransitionButtonPushed, true);
            app.MakeTransitionButton.Text = 'Make Transition';
            app.TransitionatEditFieldLabel = uilabel(app.Panel3);
            app.TransitionatEditFieldLabel.Text = 'Transition at:';
            app.TransitionInput = uieditfield(app.Panel3, 'numeric');
            app.TransitionInput.Value = 30;
            app.Panel_3 = uipanel(app.Panel3);
            app.PlayButton_3 = uibutton(app.Panel_3, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Text = 'Play';
            app.PauseButton_3 = uibutton(app.Panel_3, 'push');
            app.PauseButton_3.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_3Pushed, true);
            app.PauseButton_3.Text = 'Pause';
            app.StopButton_3 = uibutton(app.Panel_3, 'push');
            app.StopButton_3.ButtonPushedFcn = createCallbackFcn(app, @StopButton_3Pushed, true);
            app.StopButton_3.Text = 'Stop';
            app.ResumeButton_3 = uibutton(app.Panel_3, 'push');
            app.ResumeButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResumeButton_3Pushed, true);
            app.ResumeButton_3.Text = 'Resume';
            app.VolumeSlider_3Label = uilabel(app.Panel_3);
            app.VolumeSlider_3Label.Text = 'Volume';
            app.VolumeSlider_3 = uislider(app.Panel_3);
            app.VolumeSlider_3.Limits = [1 1000];
            app.VolumeSlider_3.MajorTicks = [];
            app.VolumeSlider_3.MinorTicks = [];
            app.VolumeSlider_3.Value = 1;
            app.FilenameEditFieldLabel = uilabel(app.Panel_3);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.file_name = uieditfield(app.Panel_3, 'text');
            app.file_name.Value = 'New Song';
            app.SaveButton = uibutton(app.Panel3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end